classdef App_Pretreatment_ESR_updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        ReinitializeButton           matlab.ui.control.Button
        SaveMatSwitch                matlab.ui.control.Switch
        g_value_Field                matlab.ui.control.NumericEditField
        AutomaticPeakFinderPanel     matlab.ui.container.Panel
        PeakNbEditField              matlab.ui.control.NumericEditField
        PeaknbfileLabel              matlab.ui.control.Label
        AutofindButton               matlab.ui.control.Button
        FifthEditField               matlab.ui.control.NumericEditField
        thLabel                      matlab.ui.control.Label
        FourthEditField              matlab.ui.control.NumericEditField
        thEditFieldLabel             matlab.ui.control.Label
        ThirdEditField               matlab.ui.control.NumericEditField
        rdEditFieldLabel             matlab.ui.control.Label
        gstandardButton              matlab.ui.control.StateButton
        RemovelegendButton           matlab.ui.control.StateButton
        xChangeButton                matlab.ui.control.StateButton
        BaseLineCorrectionPanel      matlab.ui.container.Panel
        BaseSwitch                   matlab.ui.control.Switch
        BaseSatisfiedCheckBox        matlab.ui.control.CheckBox
        BasePolynomeOrderSpinner     matlab.ui.control.Spinner
        PolynomeOrderSpinnerLabel    matlab.ui.control.Label
        SaveasimageButton            matlab.ui.control.Button
        CommentsTextAreaLabel        matlab.ui.control.Label
        CommentsTextArea             matlab.ui.control.TextArea
        DifferenceEditFieldLabel     matlab.ui.control.Label
        DifferenceEditField          matlab.ui.control.NumericEditField
        ToEditFieldLabel             matlab.ui.control.Label
        ToEditField                  matlab.ui.control.NumericEditField
        FromEditFieldLabel           matlab.ui.control.Label
        FromEditField                matlab.ui.control.NumericEditField
        NoisecorrectionPanel         matlab.ui.container.Panel
        NoiseOutputButton            matlab.ui.control.StateButton
        SmoothPolynomeOrderSpinner   matlab.ui.control.Spinner
        PolynomeOrderSpinnerLabel_2  matlab.ui.control.Label
        SmoothingSlider              matlab.ui.control.Slider
        SmoothingSliderLabel         matlab.ui.control.Label
        PeakIntegrationPanel         matlab.ui.container.Panel
        Int_Switch                   matlab.ui.control.Switch
        TabGroup                     matlab.ui.container.TabGroup
        BackgroundadjustmentTab      matlab.ui.container.Tab
        Bcg_FitMode_Switch           matlab.ui.control.Switch
        AdditionalfitintervalPanel   matlab.ui.container.Panel
        RightEditField               matlab.ui.control.NumericEditField
        RightEditFieldLabel          matlab.ui.control.Label
        LeftEditField                matlab.ui.control.NumericEditField
        LeftEditFieldLabel           matlab.ui.control.Label
        Bcg_InterpprecisionSpinner   matlab.ui.control.Spinner
        InterpprecisionSpinnerLabel  matlab.ui.control.Label
        Bcg_Switch                   matlab.ui.control.Switch
        Bcg_SatistfiedCheckBox       matlab.ui.control.CheckBox
        N_spinTab                    matlab.ui.container.Tab
        N_spin_Switch                matlab.ui.control.Switch
        AreaunderpeakLabel           matlab.ui.control.Label
        SaveinoutputButton           matlab.ui.control.Button
        N_spin_Label                 matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        SavedatatoExcelButton        matlab.ui.control.Button
        SendxvaluesButton            matlab.ui.control.Button
        Tree                         matlab.ui.container.Tree
        FilesLoadedLabel             matlab.ui.control.Label
        g_factorLabel                matlab.ui.control.Label
        LoadingoptionsPanel          matlab.ui.container.Panel
        ScalingParamEditField        matlab.ui.control.EditField
        ScalingparametersEditFieldLabel  matlab.ui.control.Label
        ScaleButton                  matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % needed to handle the first time the app is called
        started = false;
        ID_file_nb = 1; %Count the nb of file loaded & stored since launch
        fqcy; %The frequency of all nodes imported expressed in Hz
        Com_vect % Comment 
        Day_vect % Day on which the measurement took place
        Time_vect % Hour/min at which the measure took place.
        g_matrix % g_values
        nb_scans_vect;
        mw_power_vect;
        r_gain_vect;  
        File_names;
        
        % Ref matrices X and Y
        % Contain all data (raw, treated, absorptions)
        X = [];
        Y =  [];
        % Original spectra 
        X_raw 
        Y_raw
        % After base correction or/and smoothing
        Y_smooth = NaN;  
        Y_base_corr = NaN;   
        % Gather all the variables to be plotted
        X_plot
        Y_plot
        % Data saved 
        Peak_pos = struct;
        
        %Table help
        Memory_Table %Needed to be able to cancel one step.
        %First plot (pretreatment)
        leg ; % only refers to a cell array containing the curve names
        The_Legend % refers on the other hand to the legend object containing leg as argument
        Plot_spec   ;
        % Pretreatment output 
        BaseCorr_indices;
        B_output;
        spc_output;
        % 2nd plot - selection lines
        Absorption 
        X_abs
        Abs_bcgcorr = NaN;
        Low_XLine;
        High_XLine;  
        Third_XLine;
        Fourth_XLine;
        Fifth_XLine;
        Left_XLine;
        Right_XLine;
        
        % 3rd step : N-spin calculations 
        N_spin_value;
        Peak_areas = struct;
        %Standards
        g_std_fqcy; 
        g_std_val = 0;
        B_std;
        %Tree 
        Select_indices;
        
        %Auto-find
        B_pk_reshaped;
        
    end
    
    methods (Access = public)  
        function GUI_plot(app,x_plot,y_plot,Comments)
            %If none of the functions have been used (Ycorr&Y_raw = NaN) 
            % It's the case where the function is called from the outside
            if ~app.started 
                app.X_raw = x_plot;
                app.Y_raw = y_plot;
                app.X = app.X_raw;
                app.Y = app.Y_raw;
                app.started = true;
                app.BaseLineCorrectionPanel.Enable = 'on';
                app.CommentsTextArea.Value = Comments;
            end  
            % If not, then we plot the x_plot, y_plot lines one after the other
            nb = size(x_plot,2);
            %app.UIAxes.XLimMode = "auto"; %Automatic definition of the abcissa
            for i =1:nb
                plot(app.UIAxes,x_plot(:,i),y_plot(:,i),app.Plot_spec{i});
                hold(app.UIAxes,'on');
            end
            minX = min(min(x_plot)); maxX = max(max(x_plot));
            xlim(app.UIAxes,[minX maxX]);
            app.UIAxes.YLimMode = "auto";%Automatic definition of the ordinate
            % Finish plot options
            app.The_Legend = legend(app.UIAxes,app.leg);
            set(app.The_Legend,'Interpreter', 'none'); %To print correctly the "_" in file names
            hold(app.UIAxes,'off');            
        end
    end
    
    methods (Access = private)
        function InitToField(app,value,area_select) 
            % Function that handles the plotting of a selection line
             %Initialization of the "To" selection line
            if value==0
                %User selected 0, so the line is deleted
                if ~isempty(app.High_XLine)
                    delete(app.High_XLine);
                end
            else
                %First we check if there was a line and we delete it
                if ~isempty(app.High_XLine)
                    delete(app.High_XLine);
                end
                %Then we create a new one. 2 cases must be distinguished
                %The case where we want the pos, then we need the g_factor
                if ~area_select %case where g is needed
                    Bohr_magneton = 9.274009994*1E-24; %µ_B en Joules.Tesla−1
                    h_Planck = 6.62607015E-34; % Joules.sec
                    % Then we need to distinguish the case where a single line is plotted
                    % And when we have multiple files opened% 
                    if length(app.Tree.SelectedNodes) == 1 || app.g_std_val ~= 0
                        index = app.Tree.SelectedNodes(1).NodeData;
                        app.g_factorLabel.Text = strcat("Precise g values, with f = ", num2str(app.fqcy(ceil(index/3))/1e9),"GHz");
                       % The B value is converted from Gauss to Tesla by x1e-4
                         if app.g_std_val~= 0
                            g_val = (app.g_std_val*app.B_std/app.g_std_fqcy)*(app.fqcy(ceil(index/3))/value);
                         else
                            g_val = (h_Planck*app.fqcy(ceil(index/3)))/(Bohr_magneton*value*1e-4); 
                         end 
                    elseif app.xChangeButton.Value 
                        g_val = value;
                    else 
                        nb = length(app.Tree.SelectedNodes); 
                        indices = zeros(1,nb); fqcy_i =  zeros(1,nb);
                        for i=1:nb
                            indices(i) = app.Tree.SelectedNodes(i).NodeData;
                            fqcy_i(i) = app.fqcy(ceil(indices(i)/3));
                        end
                        g_val_vect = (h_Planck*fqcy_i)/(Bohr_magneton*value*1e-4);
                        g_val = (h_Planck*mean(fqcy_i))/(Bohr_magneton*value*1e-4);
                        g_stdev =  std(g_val_vect);
                        app.g_factorLabel.Text = strcat("Measurements taken at slightly different f, --> g_stdev =",num2str(g_stdev,'%.5f'));
                    end                   
                    % \n = new line 
                    g_label = sprintf(strcat("To\ng = ",num2str(g_val,'%.6f')));
                    app.High_XLine = xline(app.UIAxes,value,'-.r',g_label,'HandleVisibility','off');
                    app.High_XLine.LabelOrientation = 'horizontal';
                elseif area_select && app.Bcg_SatistfiedCheckBox.Value == 0
                    app.High_XLine = xline(app.UIAxes,value,'-.r','-->','HandleVisibility','off');
                    app.High_XLine.LabelOrientation = 'horizontal';
                elseif area_select && app.Bcg_SatistfiedCheckBox.Value == 1
                    app.High_XLine = xline(app.UIAxes,value,'-.r','<--','HandleVisibility','off');
                    app.High_XLine.LabelOrientation = 'horizontal';
                    app.High_XLine.LabelHorizontalAlignment = 'left';
                end        
            end          
        end
        
        function InitFromField(app,value,area_select)
            % Function that handles the plotting of one selection line
            %Initialization of the "From" selection line
            if value==0
               %User selected 0, so the line is deleted
                if ~isempty(app.Low_XLine)
                    delete(app.Low_XLine);
                end
            else
                %First we check if there was a line and we delete it
                if ~isempty(app.Low_XLine)
                    delete(app.Low_XLine);
                end
                %Then we create a new one. 2 cases must be distinguished
                %The case where we want the pos, then we need the g_factor
                if ~area_select
                    % To display the g-factor, we need:
                    Bohr_magneton = 9.274009994*1E-24; %µ_B en Joules.Tesla−1
                    h_Planck = 6.62607015E-34; % Joules.sec
                    % Then we need to distinguish the case when we have multiple files opened% 
                    if length(app.Tree.SelectedNodes) == 1 || app.g_std_val ~= 0
                        index = app.Tree.SelectedNodes(1).NodeData;
                        app.g_factorLabel.Text = strcat('Precise g values, with f = ', num2str(app.fqcy(ceil(index/3))./1e9),'GHz');
                         % The B value is converted from Gauss to Tesla by x1e-4
                         if app.g_std_val~= 0
                            g_val = (app.g_std_val*app.B_std/app.g_std_fqcy)*(app.fqcy(ceil(index/3))/value);
                         else
                            g_val = (h_Planck*app.fqcy(ceil(index/3)))/(Bohr_magneton*value*1e-4); 
                         end   
                    elseif app.xChangeButton.Value 
                        g_val = value;
                    else
                        index = zeros(1,length(app.Tree.SelectedNodes));
                        g_val_vect = zeros(1,length(app.Tree.SelectedNodes));
                        for k=1:length(app.Tree.SelectedNodes)
                            index(k) = app.Tree.SelectedNodes(k).NodeData;
                            g_val_vect = (h_Planck*app.fqcy(ceil(index(k)/3)))/(Bohr_magneton*value*1e-4);
                        end
                        g_val = mean(g_val_vect);
                        g_stdev =  std(g_val_vect);
                        app.g_factorLabel.Text = strcat("Measurements taken at slightly different f, --> g_stdev =",num2str(g_stdev,'%.5f'));
                    end    
                    g_label = sprintf(strcat("From\ng = ",num2str(g_val,'%.6f')));
                    app.Low_XLine = xline(app.UIAxes,value,'-.b',g_label,'HandleVisibility','off');
                    app.Low_XLine.LabelOrientation = 'horizontal';
                elseif area_select && app.Bcg_SatistfiedCheckBox.Value == 0
                    app.Low_XLine = xline(app.UIAxes,value,'-.b','<--','HandleVisibility','off');
                    app.Low_XLine.LabelOrientation = 'horizontal';
                    app.Low_XLine.LabelHorizontalAlignment = 'left';
                elseif area_select && app.Bcg_SatistfiedCheckBox.Value == 1
                    app.Low_XLine = xline(app.UIAxes,value,'-.b',sprintf("-->\nintegration"),'HandleVisibility','off');
                    app.Low_XLine.LabelOrientation = 'horizontal';
                    app.Low_XLine.LabelHorizontalAlignment = 'right';
                end
            end        
        end
        
        function InitXline_Nthfield(app,value,nb_line)
            % Function that handles the plotting of the 3rd, 4th and 5th Xlines  
            %%%% To display the g-factor, we need:
            Bohr_magneton = 9.274009994*1E-24; %µ_B en Joules.Tesla−1
            h_Planck = 6.62607015E-34; % Joules.sec
            % Then we need to distinguish the case when we have multiple files opened% 
            if length(app.Tree.SelectedNodes) == 1 || app.g_std_val ~= 0
                index = app.Tree.SelectedNodes(1).NodeData;
                app.g_factorLabel.Text = strcat('Precise g values, with f = ', num2str(app.fqcy(ceil(index/3))./1e9),'GHz');
                 % The B value is converted from Gauss to Tesla by x1e-4
                 if app.g_std_val~= 0
                    g_val = (app.g_std_val*app.B_std/app.g_std_fqcy)*(app.fqcy(ceil(index/3))/value);
                 else
                    g_val = (h_Planck*app.fqcy(ceil(index/3)))/(Bohr_magneton*value*1e-4); 
                 end   
            elseif app.xChangeButton.Value 
                g_val = value;
            else
                index = zeros(1,length(app.Tree.SelectedNodes));
                g_val_vect = zeros(1,length(app.Tree.SelectedNodes));
                for k=1:length(app.Tree.SelectedNodes)
                    index(k) = app.Tree.SelectedNodes(k).NodeData;
                    g_val_vect = (h_Planck*app.fqcy(ceil(index(k)/3)))/(Bohr_magneton*value*1e-4);
                end
                g_val = mean(g_val_vect);
                g_stdev =  std(g_val_vect);
                app.g_factorLabel.Text = strcat("Measurements taken at slightly different f, --> g_stdev =",num2str(g_stdev,'%.5f'));
            end    
            g_label = sprintf(num2str(g_val,'%.6f'));
            %%% g is obtained. 
           switch nb_line %We need to distinguish each line
               case 3
                   %Initialization of the line
                    if value==0   %User selected 0, so the line is deleted
                        if ~isempty(app.Third_XLine)
                            delete(app.Third_XLine);
                        end
                    else %Another value was selected, before updating we check if there was a line and we delete it
                        if ~isempty(app.Third_XLine)
                            delete(app.Third_XLine);
                        end
                        %Then we create a new one at required position:
                        app.Third_XLine = xline(app.UIAxes,value,':k',g_label,'HandleVisibility','off');
        %                 app.Left_XLine.LabelVerticalAlignment = 'bottom';
                        app.Third_XLine.LabelOrientation = 'horizontal';
                        app.Third_XLine.LabelHorizontalAlignment = 'left';
                    end      
               case 4
                   if value==0   %User selected 0, so the line is deleted
                        if ~isempty(app.Fourth_XLine)
                            delete(app.Fourth_XLine);
                        end
                    else %Another value was selected, before updating we check if there was a line and we delete it
                        if ~isempty(app.Fourth_XLine)
                            delete(app.Fourth_XLine);
                        end
                        %Then we create a new one at required position:
                        app.Fourth_XLine = xline(app.UIAxes,value,':k',g_label,'HandleVisibility','off');
        %                 app.Left_XLine.LabelVerticalAlignment = 'bottom';
                        app.Fourth_XLine.LabelOrientation = 'horizontal';
                        app.Fourth_XLine.LabelHorizontalAlignment = 'left';  
                   end
               case 5
                    if value==0   %User selected 0, so the line is deleted
                        if ~isempty(app.Fifth_XLine)
                            delete(app.Fifth_XLine);
                        end
                    else %Another value was selected, before updating we check if there was a line and we delete it
                        if ~isempty(app.Fifth_XLine)
                            delete(app.Fifth_XLine);
                        end
                        %Then we create a new one at required position:
                        app.Fifth_XLine = xline(app.UIAxes,value,':k',g_label,'HandleVisibility','off');
        %                 app.Left_XLine.LabelVerticalAlignment = 'bottom';
                        app.Fifth_XLine.LabelOrientation = 'horizontal';
                        app.Fifth_XLine.LabelHorizontalAlignment = 'left';
                    end
           end            
        end
                
        function InitLeftField(app,value)
            % Function that handles the plotting of one selection line
            %Initialization of the "From" selection line
            if value==0
               %User selected 0, so the line is deleted
                if ~isempty(app.Left_XLine)
                    delete(app.Left_XLine);
                end
            else
                %First we check if there was a line and we delete it
                if ~isempty(app.Left_XLine)
                    delete(app.Left_XLine);
                end
                %Then we create a new one.  
                app.Left_XLine = xline(app.UIAxes,value,'--k','-->','HandleVisibility','off');
%                 app.Left_XLine.LabelVerticalAlignment = 'bottom';
                app.Left_XLine.LabelOrientation = 'horizontal';
                app.Left_XLine.LabelHorizontalAlignment = 'left';
            end        
        end
        
        function InitRightField(app,value)
            % Function that handles the plotting of one selection line
            %Initialization of the "From" selection line
            if value==0
               %User selected 0, so the line is deleted
                if ~isempty(app.Right_XLine)
                    delete(app.Right_XLine);
                end
            else
                %First we check if there was a line and we delete it
                if ~isempty(app.Right_XLine)
                    delete(app.Right_XLine);
                end
                %Then we create a new one.  
                app.Right_XLine = xline(app.UIAxes,value,'--k','<--','HandleVisibility','off');
%                 app.Right_XLine.LabelVerticalAlignment = 'bottom';
                app.Right_XLine.LabelOrientation = 'horizontal';
%                 app.Right_XLine.LabelHorizontalAlignment = 'left';
            end        
        end
        
        function Update_DifferenceField(app)
            X1 = app.FromEditField.Value;
            X2 = app.ToEditField.Value;
            Diff = 0;        
            if X1 ~= 0 && X2 ~= 0
                Diff = X2-X1;
            end
            app.DifferenceEditField.Value = Diff;      
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % To be able to use all the in-built Easyspin functions
            % It is necessary to add the path where the function are located   
            addpath(genpath('C:\Users\vb264687\Documents\MATLAB\09_Easy_spin\easyspin-6.0.0-dev.39'));
            
            %Initialization of the table
            Row_raw = cell2table({'ESR=f(B)', 'raw curve'});
            app.UITable.Data = [app.UITable.Data; Row_raw];
            %Initialization of hover-comments
            Scaling_comment = sprintf("n,G,c,P divide by nb(scans),receiver gain,sampling time,sqrt(microwave power)." + ...
                "\nT multiplies by T(K) (Curie law)");
            app.LoadingoptionsPanel.Tooltip = Scaling_comment;
                
%             resetplotview(app.UIAxes);
%                zoom(app.UIAxes, 'reset'); 
%              reset(app.UIAxes);
%              app.UIAxes.Title.String = "ESR Spectra"; 
%              app.UIAxes.XLabel.String =  "Applied Magnetic Field B_{0} (Gauss)";
%              app.UIAxes.YLabel.String =  "First derivative ESR absorption (a.u)";
%              app.UIAxes.XGrid = 'on';   app.UIAxes.YGrid = 'on';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.BaseLineCorrectionPanel.Enable = 'on';
            app.started = true;
            % Let's take the scaling options input
            scale_param = app.ScalingParamEditField.Value;
            %Then we need to take into account where no scaling is desired
            if isempty(scale_param)
                [File_list,B,spc,mw_frequency,Comments,g_factor,Day,Time,nb_scans,mw_power,receiver_gain] = ExtractionEPR();
            else
                 [File_list,B,spc,mw_frequency,Comments,g_factor,Day,Time,nb_scans,mw_power,receiver_gain] = ExtractionEPR(scale_param);
            end
            % File_list contains the file names, g_factor & spc are matrices  
            % while mw_frequency is a vector and Comments a cell array. 
            
            % First let's update the tree:
            Lg = length(File_list);
            % For each loaded file, a parent tree node is created, with the
            % name of the file and as node_data a string with "P"for parent
            % Then a child node with raw data is added with Id_Data as node_data. 
            % This ID_file_nb allows to identify the (B,spc) data and match  them with the node
            
            % As 2 other children nodes can be derived by baseline/smoothing corrections 
            % + integration the increment is 3 between each Id_Data. 
            for k = 1:Lg 
                FileName = char(File_list(k));   %converts name to char
                if endsWith(FileName,'.spc')
                    FileName2 = FileName(1:end-4) ;% remove extension(.spc)
                else
                    FileName2 = FileName;
                end
                if ~isempty(scale_param)
                   FileName2 = strcat(FileName2,'_',scale_param);
                end
                FileNode = uitreenode(app.Tree,'Text',FileName2,'NodeData', strcat(string(app.ID_file_nb),"_P"));
                % A child node is added to distinguish the raw from the treated data
                uitreenode(FileNode,'Text',strcat(FileName2,'_Raw'),'NodeData',app.ID_file_nb);  
                app.ID_file_nb = app.ID_file_nb + 3; % Update ID number of the file
            end
            
             % g_factor correction
            if app.g_std_val ~= 0 %If a standard has been entered
                for i=1:size(g_factor,2) %sweep the different samples
                    for j=1:size(g_factor,1) % sweep on B
                        g_factor(j,i)= (app.g_std_val*app.B_std/app.g_std_fqcy)*(app.fqcy(i)/app.X_raw(j,i));
                    end  
                end  
            end
            % Let's now store the new values in the storage matrices/vectors
            % First we need to make a quick verification on the size of the matrices
            if length(app.X_raw)~= length(B)
                diff = length(app.X_raw)-length(B);
                if diff>0 % Meaning the imported files are shorter than the storage matrix
                    B = [B;nan(diff,size(B,2))];
                    spc = [spc;nan(diff,size(B,2))];
                    g_factor = [g_factor;nan(diff,size(B,2))];
                elseif diff<0 % Meaning the imported files are longer than the storage matrix
                    app.X_raw = [app.X_raw;nan(-1*diff,size(app.X_raw,2))];
                    app.Y_raw = [app.Y_raw;nan(-1*diff,size(app.Y_raw,2))];
                    app.g_matrix = [app.g_matrix;nan(-1*diff,size(app.g_matrix,2))];
                end
            end
 
            app.X_raw = [app.X_raw,B];
            app.Y_raw = [app.Y_raw,spc];
            % The general X and Y storage must contain X_raw and Y_raw on every 3rd column: C1 C4 C7... etc
            Wx_0 = size(app.X,2); % We store the orginal width of X;
            app.X = [app.X, zeros(length(app.X),Lg*3)]; % We add 3 times the space for the new added data
            app.Y = [app.Y,zeros(length(app.Y),Lg*3)];
            app.X(1:length(B),(Wx_0+1):3:end) = B;
            app.Y(1:length(spc),(Wx_0+1):3:end) = spc;
            app.fqcy = [app.fqcy, mw_frequency];   
            app.nb_scans_vect = [app.nb_scans_vect,nb_scans];
            app.mw_power_vect = [app.mw_power_vect,mw_power];
            app.r_gain_vect = [app.r_gain_vect,receiver_gain];
            app.Com_vect = [app.Com_vect,Comments];  
            app.Day_vect = [app.Day_vect,Day];  
            app.Time_vect = [app.Time_vect,Time];
            app.g_matrix = [app.g_matrix, g_factor];
            app.File_names = [app.File_names, File_list];
            expand(app.Tree,'all');
            figure(app.UIFigure); 
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            % It is possible to scale retroactively the signal of selected
            % nodes by pushing this button. 
            % This function takes in scaling parameters and apply
            % consequently the change on the data. The nodes are also
            % renamed accordingly
            scaleparam = app.ScalingParamEditField.Value;
            Nodes_To_Scale = app.Tree.SelectedNodes;
            
            for p =1:length(Nodes_To_Scale)
                ID_Data = Nodes_To_Scale(p).NodeData;
                switch scaleparam
                    case {'n','G','c'}
                        msg = strcat(Nodes_To_Scale(p).Text,' spectrum divided by:');
                        titleBox = 'Value request';
                        dims = [1 40];
                        Output_Val = str2double(inputdlg(msg,titleBox, dims));
                        app.Y(:,ID_Data) = app.Y(:,ID_Data)./Output_Val;
                    case {'P'}
                        msg = strcat(Nodes_To_Scale(p).Text,' spectrum divided by square-root of µwave power:');
                        titleBox = 'Value request';
                        dims = [1 40];
                        Output_Val = str2double(inputdlg(msg,titleBox, dims));
                        app.Y(:,ID_Data) = app.Y(:,ID_Data)./sqrt(Output_Val);
                    case{'T'}
                        msg = strcat(Nodes_To_Scale(p).Text,' spectrum multiplied by T(K) value (Currie Law assumed):');
                        titleBox = 'Value request';
                        dims = [1 40];
                        Output_Val = str2double(inputdlg(msg,titleBox, dims));
                        app.Y(:,ID_Data) = app.Y(:,ID_Data).*Output_Val;
                end
                app.Tree.SelectedNodes(p).Text = strcat(app.Tree.SelectedNodes(p).Text,'_', scaleparam);
            end
            TreeSelectionChanged(app,event);

            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.xChangeButton.Value = 0;
            MySelectedNodes = app.Tree.SelectedNodes;
            nb = length(MySelectedNodes);         
%-----------------------------------------------------------------------------
 flag_child_selected =  false;
% % Let's impose that when a parent node (denoted by the "_P" ending in its NodeData) is selected,
% % All of its children are selected instead.
% % As by default, the places in the SelectedNodes array are arranged following the tree view order  (--> from up to bottom, parent nodes come 1st, children next).
% % A newly selected parent node will thus come automatically first.  (the case where the first parent node has no children is however not covered)
% % The last condition is that no grouping should have occured. Otherwhise, the ID_parent will be mixed up...   
%             if endsWith(string(MySelectedNodes(1).NodeData),"_P") && ~isempty(MySelectedNodes(1).Children) 
%                 Char_ID = char(MySelectedNodes(1).NodeData);
%                 ID_parent = str2double(Char_ID(1:end-2));
%                 Min_vect = zeros(1,nb); % Vector where we'll store ID_Data - ID_parent, to locate the node compared the parent one
%                 Min_vect(1) = -5;
%                 if nb == 1 %Case where only one node is selected and it is a parent node
%                     for m = 1:length(MySelectedNodes(1).Children)
%                         app.Tree.SelectedNodes(m) = MySelectedNodes(1).Children(m);
%                     end
%                 else
%                     for k =2:nb
%                         Min_vect(k) = MySelectedNodes(k).NodeData - ID_parent;
%                         if Min_vect(k)<0 && k ~= nb % Case where the node is before the parent node in the tree, but it's not the last one
%                             app.Tree.SelectedNodes(k-1) = MySelectedNodes(k);
%                         elseif (Min_vect(k)*Min_vect(k-1))<0
%                             % Case where the node is after the parent node (+) while  previous one was before (-) (thus product is <0 )
%                             for n = 1:length(MySelectedNodes(1).Children)
%                                 app.Tree.SelectedNodes(k+n-2) = MySelectedNodes(1).Children(n); %Assigning the children nodes of the parent node
%                             end
%                             app.Tree.SelectedNodes(k+1)= MySelectedNodes(k); %Assigning next node
%                         elseif Min_vect(k) == 0 % case where the node is already a children of the parent itself
%                             for n = 1:length(MySelectedNodes(1).Children)
%                                 app.Tree.SelectedNodes(k+n-2) = MySelectedNodes(1).Children(n); %Assigning the children nodes of the parent node
%                             end
%                             flag_child_selected = true;
%                         elseif (k == nb) &&  Min_vect(k) <0  %Case where the parent node is situated at the end
%                             app.Tree.SelectedNodes(k-1) = MySelectedNodes(k);
%                             for n = 1:length(MySelectedNodes(1).Children)
%                                 app.Tree.SelectedNodes(nb+n-1) = MySelectedNodes(1).Children(n);%Assigning the children nodes of the parent node
%                             end
%                         elseif Min_vect(k)>0
%                             if flag_child_selected
%                                 app.Tree.SelectedNodes(k) = MySelectedNodes(k);
%                             else
%                                 app.Tree.SelectedNodes(k+1)= MySelectedNodes(k); % Case where node is after parent node.  
%                             end
%                         end
%                     end    
%                 end
%                end    
%  -------------------------------------------------------------------------------------------------------        
            %Initialization of the indices 
            nb = length(app.Tree.SelectedNodes);
            app.Select_indices = zeros(1,nb);
            % Recovery of the Node_data in the Select_indices vector and sorting
            for k=1:nb
               app.Select_indices(k) = app.Tree.SelectedNodes(k).NodeData;
            end
            app.Select_indices = sort(app.Select_indices);
            
            if isnan(app.Select_indices) 
                %Case where single parent node is selected but it has no more children
                return
            end
            
            % Recovery of the data to be plotted:
            %Initialization
            app.X_plot = zeros(length(app.X),nb);
            app.Y_plot = zeros(length(app.X),nb); 
            for k=1:nb
%                line_index(k) = floor(app.Select_indices(k)/3)  +1;
%                col_index(k) = rem(app.Select_indices(k),3);
               app.X_plot(:,k) = app.X(:,app.Select_indices(k));
               app.Y_plot(:,k)= app.Y(:,app.Select_indices(k));
            end   
            % Now let's take care of the plot legend, line specifications and comments
            CommentsText = strings;
            app.leg = {} ; app.Plot_spec={}; % Re-inialization
            for i=1:nb
                    category = rem(app.Tree.SelectedNodes(i).NodeData,3);
                    CommentsText = sprintf(strcat(CommentsText, "\n", app.Com_vect{ceil(app.Select_indices(i)./3)}, ...
                      " , P_µw = ", num2str(app.mw_power_vect(ceil(app.Select_indices(i)./3))),"mW  " ,...
                      app.Day_vect{ceil(app.Select_indices(i)./3)}," ",app.Time_vect{ceil(app.Select_indices(i)./3)}));
                                      %     "Receiver gain = ", num2str(app.r_gain_vect(ceil(app.Select_indices(i)./3))), "  , ", ...

                    switch category
                        case 1
                             app.leg = [app.leg; app.Tree.SelectedNodes(i).Text];
                             app.Plot_spec = [app.Plot_spec; '.'];
                        case 2
                             app.leg = [app.leg; strcat(app.Tree.SelectedNodes(i).Text,'_Treated')];
                             app.Plot_spec = [app.Plot_spec; '-'];
                        case 0
                            app.leg = [app.leg; strcat(app.Tree.SelectedNodes(i).Text,'_Abs')];
                            app.Plot_spec = [app.Plot_spec; '-.'];
%                             X_dom = app.X_plot(:,i);
%                             X_dom = X_dom(find(app.Y_plot(:,i)));
%                             app.Y_plot(:,i) = app.Y_plot(:,i)/(X_dom(round(end/4))-X_dom(1));
                    end                   
            end                        
            GUI_plot(app,app.X_plot,app.Y_plot) ;  
            app.CommentsTextArea.Value =  CommentsText;
%             
%             app.CommentsTextArea.Value = strcat(app.Com_vect{ceil(app.Select_indices./3)}," ",app.Day_vect{ceil(app.Select_indices./3)} ,...
%                 " ",app.Time_vect{ceil(app.Select_indices./3)}); 
            
            ToEditFieldValueChanged(app,event);% update g boundaries
            FromEditFieldValueChanged(app,event);
            % if the integration panel is "on", integration has to be applied
            if strcmp(app.Int_Switch.Value,'On')
                Int_SwitchValueChanged(app,event);
            end
            
            
        end

        % Button pushed function: SendxvaluesButton
        function SendxvaluesButtonPushed(app, event)
            Nb_x_val =0; %Nb of x_values to be stored.
            All_values = [];
            % Let's determine the nb_x_val to be stored 
            if app.FromEditField.Value ~= 0
                Nb_x_val = Nb_x_val+1;
                All_values = [All_values,app.FromEditField.Value];
            end
            if app.ToEditField.Value ~= 0
                Nb_x_val = Nb_x_val+1;
                All_values = [All_values,app.ToEditField.Value];
            end
            if app.ThirdEditField.Value ~= 0
                Nb_x_val = Nb_x_val+1;
                All_values = [All_values,app.ThirdEditField.Value];
            end
            if app.FourthEditField.Value ~= 0
                Nb_x_val = Nb_x_val+1;
                All_values = [All_values,app.FourthEditField.Value];
            end
            if app.FifthEditField.Value ~= 0
                Nb_x_val = Nb_x_val+1;
                All_values = [All_values,app.FifthEditField.Value];
            end        
            if Nb_x_val ==0 
                % Do nothing
            else
                % if one of the field is filled, we 1st ask the name to record  
                msg = 'Enter name of peak, only letters and _ are authorized';
                titleBox = 'Name request';
                dims = [1 40];
                Output_Name = inputdlg(msg,titleBox, dims );
                %Then we'll go over a loop and store the value in the Peak_pos structure, starting by min values 
                All_values = sort(All_values); 
                for i=1:Nb_x_val
                    value =  All_values(i);
                    str_out = strcat(Output_Name{1},num2str(i));
                    app.Peak_pos = cell2struct([struct2cell(app.Peak_pos);num2cell(value)],...
                        [fieldnames(app.Peak_pos);{str_out}]);
                    Row_B_pos_i = cell2table({str_out, num2str(value,'%.4f')} );
                    app.UITable.Data = [app.UITable.Data; Row_B_pos_i];         
                end            
            end
        end

        % Value changed function: BaseSwitch
        function BaseSwitchValueChanged(app, event)
            value = app.BaseSwitch.Value;
            %If the switch is on
            if strcmp(value,'On')
                app.Tree.Enable ='off';
                %We can activate the 'satisfied' and order polymer buttons
                app.BasePolynomeOrderSpinner.Enable ='on';
                %Then we apply the automatic base correction from Easyspin
                %The correction is applied on Y's 1st dimension (1) with a polymer (order given by spinner)
                % Here we want the baseline correction to be applied on the raw data only
                
                %First let's determine the data concerned
                app.BaseCorr_indices = [];
                for k =1:length(app.Select_indices)
                    if rem(app.Select_indices(k),3) ==1
                        app.BaseCorr_indices =  [app.BaseCorr_indices, app.Select_indices(k)];
                    end
                end
               % Then let's apply the correction 
               [app.Y_base_corr,Bline] = basecorr(app.Y(:,app.BaseCorr_indices),1,app.BasePolynomeOrderSpinner.Value);   
 
               % The corrected Y is plotted along with the base line
               app.X_plot = [app.X(:,app.BaseCorr_indices),app.X(:,app.BaseCorr_indices),app.X(:,app.BaseCorr_indices)];
               app.Y_plot = [app.Y(:,app.BaseCorr_indices),app.Y_base_corr,Bline];
               
               app.leg = {};
               app.Plot_spec ={};
                
               for i=1:length(app.BaseCorr_indices)
                    app.leg = [app.leg; {strcat(app.Tree.SelectedNodes(i).Text,'_Raw')}];
                    app.Plot_spec = [app.Plot_spec; '.'];
               end  
               for j=1:length(app.BaseCorr_indices)
                    app.leg = [app.leg; {strcat(app.Tree.SelectedNodes(j).Text,'_Corrected')}];
                    app.Plot_spec = [app.Plot_spec; '-'];
               end
               for b=1:length(app.BaseCorr_indices)
                    app.leg = [app.leg; {strcat(app.Tree.SelectedNodes(b).Text,'_BaseLine')}];
                    app.Plot_spec = [app.Plot_spec; '--'];
               end               
               
               GUI_plot(app,app.X_plot,app.Y_plot);
            else
                app.Tree.Enable ='on';
                app.BasePolynomeOrderSpinner.Enable ='off';
                TreeSelectionChanged(app, event);
            end
        end

        % Value changed function: BasePolynomeOrderSpinner
        function BasePolynomeOrderSpinnerValueChanged(app, event)
            value = app.BasePolynomeOrderSpinner.Value;
            % If the order of the polymer fitting the baseline is changed
            % The baseline correction is recalculated accordingly
            [app.Y_base_corr,Bline] = basecorr(app.Y(:,app.BaseCorr_indices),1,app.BasePolynomeOrderSpinner.Value);
            
            app.X_plot = [app.X(:,app.BaseCorr_indices),app.X(:,app.BaseCorr_indices),app.X(:,app.BaseCorr_indices)];
            app.Y_plot = [app.Y(:,app.BaseCorr_indices),app.Y_base_corr,Bline];
            
            app.leg = {};
            app.Plot_spec ={};
            
            for i=1:length(app.BaseCorr_indices)
                app.leg = [app.leg; {strcat(app.Tree.SelectedNodes(i).Text,'_Raw')}];
                app.Plot_spec = [app.Plot_spec; '.'];
            end
            for j=1:length(app.BaseCorr_indices)
                app.leg = [app.leg; {strcat(app.Tree.SelectedNodes(j).Text,'_Corrected')}];
                app.Plot_spec = [app.Plot_spec; '-'];
            end
            for b=1:length(app.BaseCorr_indices)
                app.leg = [app.leg; {strcat(app.Tree.SelectedNodes(b).Text,'_BaseLine')}];
                app.Plot_spec = [app.Plot_spec; '--'];
            end
            GUI_plot(app,app.X_plot,app.Y_plot);
        end

        % Value changed function: BaseSatisfiedCheckBox
        function BaseSatisfiedCheckBoxValueChanged(app, event)
            value = app.BaseSatisfiedCheckBox.Value;
            if value 
                %Changes of panel (de-activation of base correction / activation of noise correction)
                app.Tree.Enable ='off';
                app.BasePolynomeOrderSpinner.Enable = 'off';
                app.BaseSwitch.Enable ='off';
                if isnan(app.Y_base_corr)
                    %If no baseline modification has been carried out
                    app.Y_base_corr = app.Y_raw;
                end
                app.NoisecorrectionPanel.Enable = 'on';
                app.SmoothPolynomeOrderSpinner.Enable = 'on';
                app.SmoothingSlider.Enable = 'on';
                
                % Update of the legend, limit to the corrected lines:
                app.leg = app.leg(length(app.BaseCorr_indices)+1:2*length(app.BaseCorr_indices));
                app.Plot_spec = app.Plot_spec(1:length(app.BaseCorr_indices));
                app.Y_plot = app.Y_base_corr;
                app.X_plot = app.X(:,app.BaseCorr_indices);
                GUI_plot(app,app.X_plot,app.Y_plot);
            else
                app.BasePolynomeOrderSpinner.Enable = 'on';
                app.BaseSwitch.Enable ='on';
                app.NoisecorrectionPanel.Enable ='off';
                app.Y_base_corr = NaN;
                app.Tree.Enable ='on';

            end
        end

        % Value changed function: SmoothingSlider
        function SmoothingSliderValueChanged(app, event)
            value = app.SmoothingSlider.Value;
            % The smoothing is calculated
            app.Y_smooth = datasmooth(app.Y_base_corr,round(value),'savgol',app.SmoothPolynomeOrderSpinner.Value);
            % Then the smoothed Y curve is plotted along with the raw one
            app.X_plot = [app.X(:,app.BaseCorr_indices), app.X(:,app.BaseCorr_indices)];
            app.Y_plot = [app.Y_base_corr,app.Y_smooth];
            %Updating the legend and plot specifications
            for b=1:length(app.BaseCorr_indices)
                    app.leg = [app.leg; {strcat(app.Tree.SelectedNodes(b).Text,'_Smoothed')}];
                    app.Plot_spec = [app.Plot_spec; '-'];
            end                 
            GUI_plot(app,app.X_plot,app.Y_plot);
        end

        % Value changed function: SmoothPolynomeOrderSpinner
        function SmoothPolynomeOrderSpinnerValueChanged(app, event)
            value = app.SmoothPolynomeOrderSpinner.Value;  
            % If the order of the smoothing polymer is changed
            % The smoothing is recalculated
            app.Y_smooth = datasmooth(app.Y_base_corr,round(app.SmoothingSlider.Value),'savgol',value);
            
            % Then the smoothed Y curve is plotted along with the raw one
            app.X_plot = [app.X(:,app.BaseCorr_indices), app.X(:,app.BaseCorr_indices)];
            app.Y_plot = [app.Y_base_corr,app.Y_smooth];
            
            GUI_plot(app,app.X_plot,app.Y_plot);
        end

        % Value changed function: NoiseOutputButton
        function NoiseOutputButtonValueChanged(app, event)
            % This button is a state button (pushed --> value =1)
            value = app.NoiseOutputButton.Value; 
            if value
                % Smoothing panel deactived
                app.SmoothingSlider.Enable ='off';
                app.SmoothPolynomeOrderSpinner.Enable = 'off';
                
                
                % Saving the smoothed curves in the Y_data depository. 
                if ~isnan(app.Y_smooth)
                    app.Y(:,app.BaseCorr_indices+1) = app.Y_smooth;
                   app.X(:,app.BaseCorr_indices+1) = app.X(:,app.BaseCorr_indices);
                end
                
                %Let's now create the nodes associated witht these new data
                for k = 1:length(app.BaseCorr_indices)
                    RawNode = app.Tree.SelectedNodes(k);
                    %Node name is created from the raw one:
                    FileName = strcat(RawNode.Text(1:end-4),'_Smoothed');
                    uitreenode(RawNode.Parent,RawNode,'Text',FileName,'NodeData',app.BaseCorr_indices(k)+1);
                end
                
               % app.B_output = app.X; app.spc_output = app.Y; 
               
               %Plotting the smoothed curves:
                app.leg = app.leg(1:length(app.BaseCorr_indices));
                app.Plot_spec = app.Plot_spec(1:length(app.BaseCorr_indices));
                GUI_plot(app,app.X(:,app.BaseCorr_indices),app.Y_smooth);
                
                %Other panels are re-activated:
                app.PeakIntegrationPanel.Enable = 'on';
                app.Tree.Enable = 'on';
                app.BasePolynomeOrderSpinner.Enable = 'on';
                app.BasePolynomeOrderSpinner.Value = 1;
                app.BaseSwitch.Enable ='on';
                app.BaseSwitch.Value = 'Off';
                app.BaseSatisfiedCheckBox.Value = 0;                
                
                % Let's make the value import visible via the table
%                 Row_treated = cell2table({'ESR=f(B)', 'treated curve'}); %New row
%                 app.Memory_Table = app.UITable.Data; %Save the current table in case of cancelling
%                 app.UITable.Data = [app.UITable.Data; Row_treated];
                app.NoiseOutputButton.Value = 0;
                
            else
                app.SmoothingSlider.Enable ='on';
                app.SmoothPolynomeOrderSpinner.Enable = 'on';
                app.Y = app.Y_base_corr;  
                app.PeakIntegrationPanel.Enable = 'off';
            end
        end

        % Value changed function: FromEditField
        function FromEditFieldValueChanged(app, event)
            value = app.FromEditField.Value;
            %First the difference field is updated
            Update_DifferenceField(app);
            % Then we need to distinguish if the xline is used to define a pos
            % or an interval. By default, it is used for pos determination.
            area_select = false;
            
            %Then, if the bcg Switch or the N_spin is 'On', we're in the case of the interval
            if strcmp(app.Bcg_Switch.Value,'On')
                area_select = true;
                % the Xline is plotted.
                InitFromField(app,value,area_select); 
                % Change the fit
                Bcg_SwitchValueChanged(app,event);
            elseif strcmp(app.N_spin_Switch.Value,'On')
                area_select = true;
                % the Xline is plotted.
                InitFromField(app,value,area_select); 
                %Recompute the area (corresponding to the spin density)
                N_spin_SwitchValueChanged(app,event);   
            else % All the other cases:
                % the Xline is plotted.
                InitFromField(app,value,area_select);     
            end
        end

        % Value changed function: ToEditField
        function ToEditFieldValueChanged(app, event)
            value = app.ToEditField.Value;
            %First the difference field is updated
            Update_DifferenceField(app);
            % Then we need to distinguish if the xline is used to define a pos
            % or an interval. By default, it is used for pos determination.
            area_select = false;
            %Then, if the bcg Switch or the N_spin is 'On', we're in the case of the interval
            if strcmp(app.Bcg_Switch.Value,'On')
                area_select = true;
                % the Xline is plotted.
                InitToField(app,value,area_select); 
                % Change the fit
                Bcg_SwitchValueChanged(app,event);
            elseif strcmp(app.N_spin_Switch.Value,'On')
                area_select = true;
                % the Xline is plotted.
                InitToField(app,value,area_select); 
                %Recompute the area (corresponding to the spin density)
                N_spin_SwitchValueChanged(app,event);   
            else % All the other cases:
                % the Xline is plotted.
                InitToField(app,value,area_select);     
            end   
        end

        % Value changed function: ThirdEditField
        function ThirdEditFieldValueChanged(app, event)
            value = app.ThirdEditField.Value;
            % 1st the Xline is plotted. 
            InitXline_Nthfield(app,value,3);
        end

        % Value changed function: FourthEditField
        function FourthEditFieldValueChanged(app, event)
            value = app.FourthEditField.Value;
            InitXline_Nthfield(app,value,4);
        end

        % Value changed function: FifthEditField
        function FifthEditFieldValueChanged(app, event)
            value = app.FifthEditField.Value;
            InitXline_Nthfield(app,value,5);
        end

        % Value changed function: Int_Switch
        function Int_SwitchValueChanged(app, event)
            value = app.Int_Switch.Value;
            if strcmp(value,'On')
                %If the user clicks on, the integration takes place
                %Treatment limited to a single file, background correction and integration
                % procedures would get too complex otherwhise.
                MyNode = app.Tree.SelectedNodes(1);
                app.Tree.Multiselect = 'off';
                
                % Here we want to apply integration only to the raw file .
                % We thus need to change the node selection
                cat_index = rem(app.Tree.SelectedNodes(1).NodeData,3);
                if cat_index == 0 || 2 
                    MyNode = app.Tree.SelectedNodes(1).Parent.Children(1);
                    app.Tree.SelectedNodes(1)=MyNode;
                end
                
                X_to_treat = app.X(:,MyNode.NodeData);
                Y_to_treat = app.Y(:,MyNode.NodeData);
                for i =2:1:length(X_to_treat)
                    app.Absorption(i,1) = trapz(X_to_treat(1:i),Y_to_treat(1:i));            
                end
                app.X_plot = X_to_treat;
                app.X_abs = X_to_treat;
                app.Y_plot = app.Absorption;
                app.leg = {'Absorption'}; 
                GUI_plot(app,app.X_plot,app.Y_plot);
                ylabel(app.UIAxes, 'Integrated ESR absorption (a.u)')
                axis (app.UIAxes,'tight');
            else 
                %If the switch is back on "off", then we need to re-plot
                %original values of ESR = f(B)
                app.Tree.Multiselect = 'on';
                ylabel(app.UIAxes, 'First derivative ESR absorption (a.u)')
                TreeSelectionChanged(app,event);
            end
            
        end

        % Value changed function: Bcg_Switch
        function Bcg_SwitchValueChanged(app, event)
            value = app.Bcg_Switch.Value;
            if strcmp(value,'On')            %If the switch is on
                % Once switch is pushed, focus on a single curve, no changes allowed
                app.Tree.Enable = 'off'; 
                %We can activate the 'satisfied' and order polymer buttons
                %Then we need to take into account the input values in the To/From fields to calculate a polymer fit
                % Take the boundary values entered by user and update the labels appearance with arrows
                Low_bd = app.FromEditField.Value;
                InitFromField(app,Low_bd,true);
                High_bd = app.ToEditField.Value; 
                InitToField(app,High_bd,true);
                % Take the center values that might be included into the fit
                Left_bd = app.LeftEditField.Value;
                Right_bd = app.RightEditField.Value;
                       
                if (Left_bd*Right_bd)~=0
                    %%In the case where these 2 center values are not zero: 
                    Fit_domain = (app.X_abs<Low_bd)| (app.X_abs>Left_bd & app.X_abs<Right_bd) | (app.X_abs>High_bd);
                else
                    Fit_domain = app.X_abs <Low_bd | app.X_abs > High_bd;
                end
                
                % Then we use the interp1 function to interpolate the data on the selected fit_domain
                % First we need to select the points on which the fit will be conducted:
                % The precision is given by the spinner value, which indicate at what frequence the points will be sampled.
                % For ex: a value of 3 --> 1/3 (experimental points) taken for fitting
                
                X_interpol = app.X_abs(Fit_domain);  Y_interpol = app.Absorption(Fit_domain);
                Precision = app.Bcg_InterpprecisionSpinner.Value;
                FitMode = app.Bcg_FitMode_Switch.Value;
                X_interpol =X_interpol(1:Precision:end); Y_interpol = Y_interpol(1:Precision:end);
                  
                Bcgk = interp1(X_interpol,Y_interpol,app.X_abs,FitMode); 
                app.Abs_bcgcorr = app.Absorption - Bcgk;
                % Then the corrected Y is plotted along with the base line
                % We re-adjust the vectors X,Y_interpol lengths to display them along the others
                X_interpol(end+1:length(app.X_abs))= nan;
                Y_interpol(end+1:length(app.X_abs))= nan;
               
                app.X_plot = [app.X_abs,app.X_abs,app.X_abs,X_interpol];
                app.Y_plot = [app.Absorption,app.Abs_bcgcorr,Bcgk,Y_interpol];
                app.leg = {'Raw Absorption';'Corrected Absorption';'Base line (cubic spline)';'Interpolation Points'};
                app.Plot_spec = {'-','--','-','ko'};
                GUI_plot(app,app.X_plot,app.Y_plot);
            else
                Low_bd = app.FromEditField.Value;
                InitFromField(app,Low_bd,false);
                High_bd = app.ToEditField.Value; 
                InitToField(app,High_bd,false);
                Int_SwitchValueChanged(app,event);
                app.Tree.Enable = 'on';
            end
        end

        % Value changed function: LeftEditField
        function LeftEditFieldValueChanged(app, event)
            value = app.LeftEditField.Value;
            % 1st the Xline is plotted. 
            InitLeftField(app,value);
            Bcg_SwitchValueChanged(app,event);
        end

        % Value changed function: RightEditField
        function RightEditFieldValueChanged(app, event)
            value = app.RightEditField.Value;
            % 1st the Xline is plotted. 
            InitRightField(app,value);
            Bcg_SwitchValueChanged(app,event);
        end

        % Value changed function: Bcg_InterpprecisionSpinner
        function Bcg_InterpprecisionSpinnerValueChanged(app, event)
            value = app.Bcg_InterpprecisionSpinner.Value;
            % If the precision of the interpolation is changed by the user.
            %Then we need to recompute and redisplay the fit.
            Bcg_SwitchValueChanged(app, event);
        end

        % Value changed function: Bcg_SatistfiedCheckBox
        function Bcg_SatistfiedCheckBoxValueChanged(app, event)
            value = app.Bcg_SatistfiedCheckBox.Value;
            if value 
                %We reinitialize the values of the left/right cursors
                app.LeftEditField.Value =0; app.RightEditField.Value =0;
                InitLeftField(app,0); InitRightField(app,0)
                
                % Relevant curve is stored in Y
                if ~isnan(app.Abs_bcgcorr)
                    app.Y(:,app.Tree.SelectedNodes(1).NodeData + 2) = app.Abs_bcgcorr;  
                    app.X(:,app.Tree.SelectedNodes(1).NodeData + 2) = app.X_abs; 
                    % Then we need to create a node for the absorption
                    RawNode = app.Tree.SelectedNodes(1);
                    FileName = strcat(RawNode.Text(1:end-4),'_Absorption');
                    if length(RawNode.Parent.Children)>=2 %Case where a smoothed curve exists
                        SmoothNode = RawNode.Parent.Children(2); %Then we want the absorption to come next
                        uitreenode(RawNode.Parent,SmoothNode,'Text',FileName,'NodeData',RawNode.NodeData + 2);
                    else
                        %Node name is created from the raw one:
                        uitreenode(RawNode.Parent,RawNode,'Text',FileName,'NodeData',RawNode.NodeData + 2);    
                    end
                    
                end
                % Then we plot the curve of the corrected absorption
                app.leg = {'Corrected absorption signal'};
                GUI_plot(app,app.X_abs,app.Abs_bcgcorr);
                % And we switch the tab towards the N_spin calculations
                h2 = app.TabGroup.Children(2);
                app.TabGroup.SelectedTab = h2; 
                app.Tree.Enable = 'on'; %selection of other nodes is authorized.
                app.Tree.Multiselect ='on';
                app.Int_Switch.Value = 'Off';
                app.Bcg_Switch.Value = 'Off';
                app.Bcg_SatistfiedCheckBox.Value = 0;
                
                
            else
                app.BasePolynomeOrderSpinner.Enable = 'on';
                app.BaseSwitch.Enable ='on';
                h1 = app.TabGroup.Children(1);
                app.TabGroup.SelectedTab = h1; 
                app.Abs_bcgcorr = NaN;
                Bcg_SwitchValueChanged(app, event)
            end
        end

        % Value changed function: N_spin_Switch
        function N_spin_SwitchValueChanged(app, event)
            value = app.N_spin_Switch.Value;
            
            if strcmp(value,'On')
                % As when we obtained the absorption curves, we will  limit  ourselves to a single sample curve:
                app.Tree.Multiselect = 'off';
                
                % This curve has to be an absorption one
                % We thus need to change the node selection
                cat_index = rem(app.Tree.SelectedNodes(1).NodeData,3);
                if cat_index == 1 || 2 
                    MyNode = app.Tree.SelectedNodes(1).Parent.Children(3);
                    app.Tree.SelectedNodes(1)= MyNode;
                end
                GUI_plot(app,app.X(:,app.Tree.SelectedNodes(1).NodeData),app.Y(:,app.Tree.SelectedNodes(1).NodeData));
                % We then get the boundaries of the integration domain:
                Low_bd = app.FromEditField.Value; 
                High_bd = app.ToEditField.Value;
                Int_domain = app.X(:,app.Tree.SelectedNodes(1).NodeData) > Low_bd & app.X(:,app.Tree.SelectedNodes(1).NodeData) < High_bd;
                
                app.N_spin_value = trapz(app.X(Int_domain,app.Tree.SelectedNodes(1).NodeData),app.Y(Int_domain,app.Tree.SelectedNodes(1).NodeData));  
                app.N_spin_Label.Visible = 'on';
                app.N_spin_Label.Text = strcat('Area under peak = ',num2str(app.N_spin_value,'%.5e'));
            elseif strcmp(value,'Off')
                app.N_spin_Label.Text ='';
                app.Tree.Multiselect = 'on';
            end           
                
        end

        % Button pushed function: SaveinoutputButton
        function SaveinoutputButtonPushed(app, event)
            % First we need ask the name of the the peak via inputdlg
            msg = 'Enter name of peak, only letters and _ authorized';
            titleBox = 'Name request';
            dims = [1 40];
            Output_Name = inputdlg(msg,titleBox, dims );
            % Once the name is obtained, the value is stored in the Peak_areas structure 
            app.Peak_areas = cell2struct([struct2cell(app.Peak_areas);num2cell(app.N_spin_value)],...
                [fieldnames(app.Peak_areas);Output_Name]);
            
            Row_Pk = cell2table({Output_Name{1}, num2str(app.N_spin_value,'%.5e')}); %New row
  %         app.Memory_Table = app.UITable.Data; %Save the current table in case of cancelling
            app.UITable.Data = [app.UITable.Data; Row_Pk];
            app.Tree.Multiselect = 'on';
            app.N_spin_Switch.Value = 'Off';

        end

        % Value changed function: Bcg_FitMode_Switch
        function Bcg_FitMode_SwitchValueChanged(app, event)
            value = app.Bcg_FitMode_Switch.Value;
             % If the precision of the mode of interpolation is changed by the user.
            %Then we need to recompute and redisplay the fit.
            Bcg_SwitchValueChanged(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
               CloseAns = uiconfirm(app.UIFigure,'Close document?','Confirm Close',...
                'CloseFcn', @(src,event)mycallback(app,src,event) );
            if strcmp(CloseAns,'OK')
                delete(app);
            end
        end

        % Button pushed function: SavedatatoExcelButton
        function SavedatatoExcelButtonPushed(app, event)
%%   Saving Function
            app.SavedatatoExcelButton.UserData = 1;  %If app called from exterior  
            colname_array = cell(1,length(app.Select_indices)*4); %cell array containing {X_namesample_i,B_corr_namesample_i, g_namesample_i, Y_namesample_i, ... etc};
            curves_data = NaN(length(app.X),length(app.Select_indices)*3);
            nb_scan = zeros(1,length(app.Select_indices));
            day = cell(1,length(app.Select_indices));
            frqcy = nb_scan; mw_power = nb_scan; rgain = nb_scan;   
            hour = day; comments = day;  FileNames = day;
            Bohr_magneton = 9.274009994*1E-24; %µ_B en Joules.Tesla−1
            h_Planck = 6.62607015E-34; % Joules.sec
             for k=1:length(app.Select_indices)
                 %We want to save only the selected data
                 curves_data(:,4*k-3) = app.X(:,app.Select_indices(k));
                 curves_data(:,4*k-1) = app.g_matrix(:,ceil(app.Select_indices(k)/3));
                 curves_data(:,4*k) = app.Y(:,app.Select_indices(k));
                 FileName_k = app.Tree.SelectedNodes(k).Text;
                 colname_array{1,4*k-3} = strcat('B_',FileName_k);
                 colname_array{1,4*k-2}= strcat('B_corr',FileName_k);
                 colname_array{1,4*k-1} = strcat('g_',FileName_k);
                 colname_array{1,4*k} = strcat('Y_',FileName_k);
                 nb_scan(k) = app.nb_scans_vect(ceil(app.Select_indices(k)/3));
                 frqcy(k) = app.fqcy(ceil(app.Select_indices(k)/3));
                 mw_power(k) = app.mw_power_vect(ceil(app.Select_indices(k)/3));
                 rgain(k) = app.r_gain_vect(ceil(app.Select_indices(k)/3));
                 day(k) = app.Day_vect(ceil(app.Select_indices(k)/3));
                 hour(k) = app.Time_vect(ceil(app.Select_indices(k)/3));
                 comments(k) = app.Com_vect(ceil(app.Select_indices(k)/3));
                 FileNames{k} = FileName_k;
% On top of the "classic" parameters, we propose to add a "real_magnetic field" vector.
% As noted previously, the magnetic field sensors are slighly unprecise and further simulations from data will yield shifted g values. 
% This real magnetic field is the one corrected by the standard and aims to be used as input. It does not match the one measured. 
                factor_scale = app.g_matrix(floor(length(app.g_matrix)/2),ceil(app.Select_indices(k)/3))/((h_Planck*frqcy(k))/(Bohr_magneton*1e-4*...
                    app.X(floor(length(app.g_matrix)/2),app.Select_indices(k))));
                curves_data(:,4*k-2) = curves_data(:,4*k-3)/factor_scale;
             end             
            curves_table = table(curves_data); % curves_data is transformed in table 
           
            %Then table is modified to add the names
            curves_table = splitvars(curves_table,curves_table.Properties.VariableNames,'NewVariableNames',colname_array); 
            
            
            %curves_table.Properties.VariableNames = {'X','Y'};
            msg = 'Enter name of excel file to be saved';
            titleBox = 'Name request';
            dims = [1 50];
            Output_Name = inputdlg(msg,titleBox, dims);
            Name = strcat(Output_Name{1},'.xlsm');
            writetable(curves_table,Name,'Sheet','Curves');
           
            Param_names =  {'Nb scans', 'Mw frequency','Mw power','Receiver Gain','Day','Hour','Comments'};
            Param_table = table(nb_scan',frqcy',mw_power',rgain',day',hour',comments', 'VariableNames',Param_names ,'RowNames',FileNames');
            writetable(Param_table,Name,'Sheet','Parameters','WriteRowNames',true);
            
            T1 = struct2table(app.Peak_areas); 
            T2 = struct2table(app.Peak_pos); 
            T = [T1 T2]; 
            writetable(T, Name,'Sheet','Analysis'); 
            
             if strcmp(app.SaveMatSwitch.Value, 'On' )
                    MatName = strcat(Output_Name,'.mat');
                    TabName = strcat(Output_Name,'_tab','.mat');
                    MatName = char(MatName);  TabName = char(TabName);
                    save(MatName,'colname_array','curves_data');
                    save(TabName,'Param_table');
             end
        end

        % Button pushed function: SaveasimageButton
        function SaveasimageButtonPushed(app, event)
            msg = 'Name of the image to save:';
            titleBox = 'Name request';
            dims = [1 40];
            default_Name = {'ESR_'};
            Output_Name = inputdlg(msg,titleBox, dims  ,default_Name);
            Complete_name = strcat(string(pwd),'\',Output_Name,'.png');
            exportgraphics(app.UIAxes, char(Complete_name));
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
% ------------------------- from Sinton -----------------------          
% % Deleting the treated data
%             % This is needed if a new treatment is done. As a mix of former and new data might then appear.
%             for k = 1:app.Treated_counter
%                 % Retrieval of ID number of the data, from the matrix where they were collected
%                 ID_Data = app.Tree.SelectedNodes(app.Index_Treated(k)).NodeData;
%                 % Data retrieval from treated database
%                 app.TreatedData_Storage(:,2*ID_Data-1:2*ID_Data)= NaN(app.MCD_L,2);
%                 app.X_treated(:,ID_Data)=NaN(app.MCD_L,1);
%                 app.Y_treated(:,ID_Data)=NaN(app.MCD_L,1);
%             end
% -----------------------------------------------------------------------------------     
            delete(app.Tree.SelectedNodes);
        end

        % Value changed function: xChangeButton
        function xChangeButtonValueChanged(app, event)
            value = app.xChangeButton.Value;
            if value
                % When the measurements have been performed with a significant frequency gap
                %Then it might be useful to direcly plot the curves in function of the g-factor (Landé factor) 
                MySelectedNodes = app.Tree.SelectedNodes;
                nb = length(MySelectedNodes);
                X_To_plot = zeros(length(app.X),nb);  Y_To_plot = zeros(length(app.X),nb);
                for i = 1:nb
                    % We use a trick to get 1 for (1 2 3), 2 for (4 5 6)... etc. 
                    ID_g = ceil(MySelectedNodes(i).NodeData/3);
                    Y_To_plot(:,i) = app.Y(:,MySelectedNodes(i).NodeData);
                    X_To_plot(:,i) = app.g_matrix(:,ID_g);                
                end
                minX = min(min(X_To_plot)); maxX = max(max(X_To_plot));
                GUI_plot(app,X_To_plot,Y_To_plot);
                xlim(app.UIAxes,[minX maxX]);
            else
                TreeSelectionChanged(app,event);
            end
           
        end

        % Value changed function: RemovelegendButton
        function RemovelegendButtonValueChanged(app, event)
            value = app.RemovelegendButton.Value;
            if value ==1
                set(app.The_Legend, 'Visible', 'off');
            elseif value == 0                
                set(app.The_Legend,'Visible', 'on');
            end
        end

        % Button pushed function: AutofindButton
        function AutofindButtonPushed(app, event)
            % This function automatically finds the (B,g) precise positions of the peaks of the ESR files loaded
            % These positions are reported in the numerical editable fields below (From, To, 3rd, 4th, 5th)
            % 1st let's take the number of peaks to be detected per file.
            Nb_pk = app.PeakNbEditField.Value; 
            %Initialization of the storage
            B_pk = zeros(Nb_pk,length(app.Select_indices));
            spc_pk =  B_pk;
            if Nb_pk ==0
                %If no peak number has been given, end of script.
                return;
            end
            %A verification on data type (raw, smoothed, absorption) could be useful to exclude absorption spectra or develop a separate algorithm for these)
            %Let's scan the selected nodes one after the other. 
            for k =1:length(app.Select_indices) 
                X_sel = app.X(:,app.Select_indices(k));
                Y_sel = app.Y(:,app.Select_indices(k));
                % Let's look for the N (Nb_pk) most "prominent" extrema in the spectrum
                % We first determine the prominence of all local min/max
                [~,P_min] = islocalmin(Y_sel);  [~,P_max] = islocalmax(Y_sel);
                %Then tolMax and tolMin are defined as the prominence of the least prominent extremum that we want to take into account  
                tolMax = min(maxk(P_max,Nb_pk));  tolMin = min(maxk(P_min,Nb_pk));
                %Then, the indices of these local extrema are stored in TF_max and TF_min
                TF_max = islocalmax(Y_sel,'MinProminence',tolMax); TF_min = islocalmin(Y_sel,'MinProminence',tolMin);
                indices_max = find(TF_max); indices_min = find(TF_min);
                % As ESR signal = derivative,  g-value matching  max of  absorption is when the derivative nullifies between its max and min value
                % We have the extrema so now needs to look for the zero  in between each couple of (max_i, min_i) by interpolating them at zero. 
                for i =1:1:Nb_pk
                    %Let's define the interval intermax/min
                    X_interval = X_sel(indices_max(i):indices_min(i)); 
                    Y_interval = Y_sel(indices_max(i):indices_min(i));
                    % Now let's store the value interpolated for Y=0 in B_pk vector
                    B_pk(i,k) = interp1(Y_interval,X_interval, 0,"pchip");
                end
                %Let's prepare the legend and matrices for further plotting.
                app.leg = [app.leg; {strcat(app.Tree.SelectedNodes(k).Text, '_zeros')}];
                app.Plot_spec = [app.Plot_spec; '*r'];
            end
            % Let's then complete the X_plot and Y_plot matrices  
            X_pts = [B_pk;  NaN(length(app.X_plot)-Nb_pk ,size(B_pk,2))];
            Y_pts = [spc_pk; NaN(length(app.X_plot)- Nb_pk, size(B_pk,2))];
            app.X_plot = [app.X_plot, X_pts];
            app.Y_plot = [app.Y_plot, Y_pts];
            GUI_plot(app,app.X_plot,app.Y_plot);
            app.B_pk_reshaped = reshape(B_pk,[],1);
            app.B_pk_reshaped = [app.B_pk_reshaped; NaN(5,1)];
            %Then we'll enter the values of B reported in the Edit_fields, to enable user to save them easily 
            if ~isnan(app.B_pk_reshaped(1))
                app.ThirdEditField.Value = app.B_pk_reshaped(1);
                ThirdEditFieldValueChanged(app, event);
            end
            if ~isnan(app.B_pk_reshaped(2))
                app.FourthEditField.Value = app.B_pk_reshaped(2);
                FourthEditFieldValueChanged(app, event);
            end
            if ~isnan(app.B_pk_reshaped(3))
                app.FifthEditField.Value = app.B_pk_reshaped(3);
                FifthEditFieldValueChanged(app,event);
            end
            if ~isnan(app.B_pk_reshaped(4))
                app.FromEditField.Value = app.B_pk_reshaped(4);
                FromEditFieldValueChanged(app,event);
            end
            if ~isnan(app.B_pk_reshaped(5))
                app.ToEditField.Value = app.B_pk_reshaped(5);
                ToEditFieldValueChanged(app,event);
            end
        
        end

        % Value changed function: gstandardButton
        function gstandardButtonValueChanged(app, event)
            value = app.gstandardButton.Value;
            % As the Hall probe used in the spectrometer is not located at the sample position, a g-standard is commonly used.
            app.Tree.Multiselect =  'off'; % When button is pressed: only one selected file allowed . 
            MyNode = app.Tree.SelectedNodes(1);  
            % Here we want to access the raw file. As a standard sample, it should not necessitate any smoothing/baseline correction.  
            % We thus need to change the node selection if needed
            cat_index = rem(app.Tree.SelectedNodes(1).NodeData,3);
            if cat_index == 0 || 2 
                MyNode = app.Tree.SelectedNodes(1).Parent.Children(1);
                app.Tree.SelectedNodes(1)=MyNode;
            end
            %Let's change the appearance of the tree node related to the standard to show the action
            s1 = uistyle("BackgroundColor",[0.73,0.84,0.91]);
            % addStyle(app.Tree,s1,"node",MyNode); %To be added with new  Matlab version
    
            %Let's now access the data from the Node selected:
            X_std = app.X(:,MyNode.NodeData);   Y_std = app.Y(:,MyNode.NodeData);
            % The microwave frequency of the measurement is stored
            app.g_std_fqcy = app.fqcy(ceil(MyNode.NodeData/3));
            % Let's ask user the number of resonance in the spectrum.
            msgNb = "Enter number of peaks";
            titleBox = 'Value request';
            dims = [1 40];
            nb_pk = str2double(inputdlg(msgNb,titleBox, dims));
            app.PeakNbEditField.Value = nb_pk; 
            %Let's now use the auto-find function to find the precise B values
            AutofindButtonPushed(app, event);
            g_values = zeros(1,nb_pk);
            %%%%% General case = single peak in standard spectrum %%%%
            if nb_pk ==1 
                hold(app.UIAxes,"on");
                plot(app.UIAxes,app.B_pk_reshaped(1),0,'ro','MarkerSize',15,'LineWidth',2);
                pause(1)
                msg1 = 'Was the peak found correctly?';
                title1 = 'Confirm peak';
                satisfied_ans = uiconfirm(app.UIFigure,msg1,title1, 'Options',{'Yes', 'No, enter manually'},'DefaultOption',1);
                app.B_std = app.B_pk_reshaped(1);%By defect, we consider 'Yes' for answer. If not, B_std will be rewritten 
                if strcmp(satisfied_ans,'No, enter manually') %Case where the autofinding didn't work out
                      app.ToEditField.Enable = 'off'; app.ThirdEditField.Enable = 'off';
                      app.FourthEditField.Enable = 'off'; app.FifthEditField.Enable = 'off';
                      app.FromEditField.BackgroundColor = [0.95,0.71,0.64];  app.FromEditField.Value = 0;
                      while(app.FromEditField.Value ==0)
                          pause(1)
                      end
                      app.ToEditField.Enable = 'on'; app.ThirdEditField.Enable = 'on';
                      app.FourthEditField.Enable = 'on'; app.FifthEditField.Enable = 'on';
                      app.FromEditField.BackgroundColor = [1.00,1.00,1.00];
                      app.B_std = app.FromEditField.Value; %Rewriting over B_std with the input from user. 
                end
                msg = "Enter the g value of the peak";
                titleBox = 'Value request';
                dims = [1 40];
                app.g_std_val = str2double(inputdlg(msg,titleBox, dims));  
            end
            
            %%%% More than one peak: additionnal problematics %%%
            if nb_pk >=2
                msg2 = 'Spectrum contains more than one peak. Should only the center value be considered (case hyperfine splitting) or all of them ?';
                title2 = 'Case Multiple peaks: options';
                Choice_ans = uiconfirm(app.UIFigure,msg2,title2,'Options',{'Single g <--> B center value','All g needed'},'DefaultOption',1); 
                currentXLim = app.UIAxes.XLim;
                currentYLim = app.UIAxes.YLim;
                for i=1:nb_pk
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,app.B_pk_reshaped(i),0,'ro','MarkerSize',15,'LineWidth',2);
                    pause(2.5)
                    msg1 = 'Was the peak found correctly?';
                    title1 = 'Confirm peak';
                    satisfied_ans = uiconfirm(app.UIFigure,msg1,title1, 'Options',{'Yes', 'No, enter manually'},'DefaultOption',1);  
                    if strcmp(satisfied_ans,'No, enter manually') %Case where the autofinding didn't work out
                         app.ThirdEditField.Enable = 'off';
                        app.FourthEditField.Enable = 'off'; app.FifthEditField.Enable = 'off';
                        app.FromEditField.BackgroundColor = [0.95,0.71,0.64];  app.FromEditField.Value = 0;
                        while(app.FromEditField.Value ==0)
                            pause(2)
                        end
                        app.ThirdEditField.Enable = 'on';
                        app.FourthEditField.Enable = 'on'; app.FifthEditField.Enable = 'on';
                        app.FromEditField.BackgroundColor = [1.00,1.00,1.00];
                        app.B_pk_reshaped(i) = app.FromEditField.Value; %Rewriting over B_std with the input from user, inside B_pk_reshaped here  ;
                        app.UIAxes.XLim = currentXLim;
                        app.UIAxes.YLim = currentYLim;
                    end
                    if strcmp(Choice_ans,'All g needed')
                        msg_g = "Enter the g value of the peak";
                        titleBox = 'Value request';
                        dims = [1 40];
                        g_values(i) = str2double(inputdlg(msg_g,titleBox, dims));  
                    end
                end
                if strcmp(Choice_ans,'Single g <--> B center value')
                    app.B_std = mean(app.B_pk_reshaped(1:nb_pk));
                    msg_g = strcat("The B_avg measured is ",num2str(app.B_std),". Enter the g-factor that corresponds");
                    titleBox = 'Value request';
                    dims = [1 40];
                    app.g_std_val = str2double(inputdlg(msg_g,titleBox, dims));
                elseif strcmp(Choice_ans,'All g needed')
                    msg_all_g = "For now, no solution taking into account all peaks is provide. One peak should be taken as ref, which one ?";
                    titleBox = 'Value request';
                    dims = [1 40];
                    num_pk = str2double(inputdlg(msg_all_g,titleBox, dims));
                    app.g_std_val = g_values(num_pk);
                    app.B_std = app.B_pk_reshaped(num_pk);
                end
            end       
            app.g_value_Field.Value = app.g_std_val;
            
      %%%% Final Part %%%
            % Let's now update the g_matrix with this standard value:
            for i=1:size(app.g_matrix,2) %sweep over samples
                for j=1:size(app.g_matrix,1) %sweep on B
                    app.g_matrix(j,i)= (app.g_std_val*app.B_std/app.g_std_fqcy)*(app.fqcy(i)/app.X_raw(j,i));
                end
            end
            app.Tree.Multiselect =  'on';         
        end

        % Button pushed function: ReinitializeButton
        function ReinitializeButtonPushed(app, event)
            app.FromEditField.Value = 0;
            app.ToEditField.Value = 0;
            app.ThirdEditField.Value =0;
            app.FourthEditField.Value = 0;
            app.FifthEditField.Value = 0;
            area_select = false;
            InitFromField(app,0,area_select);
            InitToField(app,0,area_select);
            InitXline_Nthfield(app,0,3);
            InitXline_Nthfield(app,0,4);
            InitXline_Nthfield(app,0,5);
 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'ESR Spectra')
            xlabel(app.UIAxes, 'Applied Magnetic Field B_{0} (Gauss)')
            ylabel(app.UIAxes, '1st derivative ESR absorption (a.u)')
            zlabel(app.UIAxes, 'Z')
            app.LoadingoptionsPanel = uipanel(app.GridLayout);
            app.LoadingoptionsPanel.Title = '0 - Loading options';
            app.LoadButton = uibutton(app.LoadingoptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DeleteButton = uibutton(app.LoadingoptionsPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ScaleButton = uibutton(app.LoadingoptionsPanel, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.ScalingparametersEditFieldLabel = uilabel(app.LoadingoptionsPanel);
            app.ScalingparametersEditFieldLabel.Text = 'Scaling parameters';
            app.ScalingParamEditField = uieditfield(app.LoadingoptionsPanel, 'text');
            app.g_factorLabel = uilabel(app.GridLayout);
            app.g_factorLabel.Text = {'g_factor'; ''};
            app.FilesLoadedLabel = uilabel(app.GridLayout);
            app.FilesLoadedLabel.Text = 'Files Loaded';
            app.Tree = uitree(app.GridLayout);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.SendxvaluesButton = uibutton(app.GridLayout, 'push');
            app.SendxvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @SendxvaluesButtonPushed, true);
            app.SendxvaluesButton.Text = 'Send x values';
            app.SavedatatoExcelButton = uibutton(app.GridLayout, 'push');
            app.SavedatatoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatoExcelButtonPushed, true);
            app.SavedatatoExcelButton.Text = ' Save data to Excel';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Name'; 'Value'};
            app.UITable.RowName = {};
            app.PeakIntegrationPanel = uipanel(app.GridLayout);
            app.PeakIntegrationPanel.Enable = 'off';
            app.PeakIntegrationPanel.Title = '3 - Peak Integration';
            app.TabGroup = uitabgroup(app.PeakIntegrationPanel);
            app.BackgroundadjustmentTab = uitab(app.TabGroup);
            app.BackgroundadjustmentTab.Title = '3.1 - Background adjustment';
            app.Bcg_SatistfiedCheckBox = uicheckbox(app.BackgroundadjustmentTab);
            app.Bcg_SatistfiedCheckBox.ValueChangedFcn = createCallbackFcn(app, @Bcg_SatistfiedCheckBoxValueChanged, true);
            app.Bcg_SatistfiedCheckBox.Text = 'Satisfied';
            app.Bcg_Switch = uiswitch(app.BackgroundadjustmentTab, 'slider');
            app.Bcg_Switch.ValueChangedFcn = createCallbackFcn(app, @Bcg_SwitchValueChanged, true);
            app.InterpprecisionSpinnerLabel = uilabel(app.BackgroundadjustmentTab);
            app.InterpprecisionSpinnerLabel.Text = 'Interp precision';
            app.Bcg_InterpprecisionSpinner = uispinner(app.BackgroundadjustmentTab);
            app.Bcg_InterpprecisionSpinner.Limits = [1 25];
            app.Bcg_InterpprecisionSpinner.ValueChangedFcn = createCallbackFcn(app, @Bcg_InterpprecisionSpinnerValueChanged, true);
            app.Bcg_InterpprecisionSpinner.Value = 1;
            app.AdditionalfitintervalPanel = uipanel(app.BackgroundadjustmentTab);
            app.AdditionalfitintervalPanel.Title = 'Additional fit interval';
            app.LeftEditFieldLabel = uilabel(app.AdditionalfitintervalPanel);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftEditField = uieditfield(app.AdditionalfitintervalPanel, 'numeric');
            app.LeftEditField.ValueChangedFcn = createCallbackFcn(app, @LeftEditFieldValueChanged, true);
            app.RightEditFieldLabel = uilabel(app.AdditionalfitintervalPanel);
            app.RightEditFieldLabel.Text = 'Right ';
            app.RightEditField = uieditfield(app.AdditionalfitintervalPanel, 'numeric');
            app.RightEditField.ValueChangedFcn = createCallbackFcn(app, @RightEditFieldValueChanged, true);
            app.Bcg_FitMode_Switch = uiswitch(app.BackgroundadjustmentTab, 'slider');
            app.Bcg_FitMode_Switch.Items = {'spline', 'pchip'};
            app.Bcg_FitMode_Switch.ValueChangedFcn = createCallbackFcn(app, @Bcg_FitMode_SwitchValueChanged, true);
            app.Bcg_FitMode_Switch.Value = 'spline';
            app.N_spinTab = uitab(app.TabGroup);
            app.N_spinTab.Title = '3.2 - N_spin ';
            app.N_spin_Label = uilabel(app.N_spinTab);
            app.N_spin_Label.Visible = 'off';
            app.SaveinoutputButton = uibutton(app.N_spinTab, 'push');
            app.SaveinoutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinoutputButtonPushed, true);
            app.SaveinoutputButton.Text = 'Save in output';
            app.AreaunderpeakLabel = uilabel(app.N_spinTab);
            app.AreaunderpeakLabel.Text = 'Area under peak';
            app.N_spin_Switch = uiswitch(app.N_spinTab, 'slider');
            app.N_spin_Switch.ValueChangedFcn = createCallbackFcn(app, @N_spin_SwitchValueChanged, true);
            app.Int_Switch = uiswitch(app.PeakIntegrationPanel, 'slider');
            app.Int_Switch.ValueChangedFcn = createCallbackFcn(app, @Int_SwitchValueChanged, true);
            app.NoisecorrectionPanel = uipanel(app.GridLayout);
            app.NoisecorrectionPanel.Enable = 'off';
            app.NoisecorrectionPanel.Title = '2 - Noise correction';
            app.SmoothingSliderLabel = uilabel(app.NoisecorrectionPanel);
            app.SmoothingSliderLabel.Text = 'Smoothing';
            app.SmoothingSlider = uislider(app.NoisecorrectionPanel);
            app.SmoothingSlider.Limits = [0 50];
            app.SmoothingSlider.MajorTicks = [0 10 20 30 40 50];
            app.SmoothingSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50'};
            app.SmoothingSlider.ValueChangedFcn = createCallbackFcn(app, @SmoothingSliderValueChanged, true);
            app.SmoothingSlider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50];
            app.PolynomeOrderSpinnerLabel_2 = uilabel(app.NoisecorrectionPanel);
            app.PolynomeOrderSpinnerLabel_2.Text = 'Polynome Order';
            app.SmoothPolynomeOrderSpinner = uispinner(app.NoisecorrectionPanel);
            app.SmoothPolynomeOrderSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothPolynomeOrderSpinnerValueChanged, true);
            app.SmoothPolynomeOrderSpinner.Value = 2;
            app.NoiseOutputButton = uibutton(app.NoisecorrectionPanel, 'state');
            app.NoiseOutputButton.ValueChangedFcn = createCallbackFcn(app, @NoiseOutputButtonValueChanged, true);
            app.NoiseOutputButton.Text = 'Save as output';
            app.FromEditField = uieditfield(app.GridLayout, 'numeric');
            app.FromEditField.ValueChangedFcn = createCallbackFcn(app, @FromEditFieldValueChanged, true);
            app.FromEditFieldLabel = uilabel(app.GridLayout);
            app.FromEditFieldLabel.Text = 'From';
            app.ToEditField = uieditfield(app.GridLayout, 'numeric');
            app.ToEditField.ValueChangedFcn = createCallbackFcn(app, @ToEditFieldValueChanged, true);
            app.ToEditFieldLabel = uilabel(app.GridLayout);
            app.ToEditFieldLabel.Text = 'To';
            app.DifferenceEditField = uieditfield(app.GridLayout, 'numeric');
            app.DifferenceEditField.Editable = 'off';
            app.DifferenceEditFieldLabel = uilabel(app.GridLayout);
            app.DifferenceEditFieldLabel.Text = 'Difference';
            app.CommentsTextArea = uitextarea(app.GridLayout);
            app.CommentsTextAreaLabel = uilabel(app.GridLayout);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.SaveasimageButton = uibutton(app.GridLayout, 'push');
            app.SaveasimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasimageButtonPushed, true);
            app.SaveasimageButton.Text = 'Save as image';
            app.BaseLineCorrectionPanel = uipanel(app.GridLayout);
            app.BaseLineCorrectionPanel.Enable = 'off';
            app.BaseLineCorrectionPanel.Title = '1 - Base Line Correction';
            app.PolynomeOrderSpinnerLabel = uilabel(app.BaseLineCorrectionPanel);
            app.PolynomeOrderSpinnerLabel.Text = 'Polynome Order';
            app.BasePolynomeOrderSpinner = uispinner(app.BaseLineCorrectionPanel);
            app.BasePolynomeOrderSpinner.Limits = [0 6];
            app.BasePolynomeOrderSpinner.ValueChangedFcn = createCallbackFcn(app, @BasePolynomeOrderSpinnerValueChanged, true);
            app.BasePolynomeOrderSpinner.Value = 1;
            app.BaseSatisfiedCheckBox = uicheckbox(app.BaseLineCorrectionPanel);
            app.BaseSatisfiedCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaseSatisfiedCheckBoxValueChanged, true);
            app.BaseSatisfiedCheckBox.Text = 'Satisfied';
            app.BaseSwitch = uiswitch(app.BaseLineCorrectionPanel, 'slider');
            app.BaseSwitch.ValueChangedFcn = createCallbackFcn(app, @BaseSwitchValueChanged, true);
            app.xChangeButton = uibutton(app.GridLayout, 'state');
            app.xChangeButton.ValueChangedFcn = createCallbackFcn(app, @xChangeButtonValueChanged, true);
            app.xChangeButton.Text = 'Change x-axis to g';
            app.RemovelegendButton = uibutton(app.GridLayout, 'state');
            app.RemovelegendButton.ValueChangedFcn = createCallbackFcn(app, @RemovelegendButtonValueChanged, true);
            app.RemovelegendButton.Text = 'Remove legend';
            app.gstandardButton = uibutton(app.GridLayout, 'state');
            app.gstandardButton.ValueChangedFcn = createCallbackFcn(app, @gstandardButtonValueChanged, true);
            app.gstandardButton.Text = 'g-standard';
            app.rdEditFieldLabel = uilabel(app.GridLayout);
            app.rdEditFieldLabel.Text = '3rd';
            app.ThirdEditField = uieditfield(app.GridLayout, 'numeric');
            app.ThirdEditField.ValueChangedFcn = createCallbackFcn(app, @ThirdEditFieldValueChanged, true);
            app.thEditFieldLabel = uilabel(app.GridLayout);
            app.thEditFieldLabel.Text = '4th';
            app.FourthEditField = uieditfield(app.GridLayout, 'numeric');
            app.FourthEditField.ValueChangedFcn = createCallbackFcn(app, @FourthEditFieldValueChanged, true);
            app.thLabel = uilabel(app.GridLayout);
            app.thLabel.Text = '5th';
            app.FifthEditField = uieditfield(app.GridLayout, 'numeric');
            app.FifthEditField.ValueChangedFcn = createCallbackFcn(app, @FifthEditFieldValueChanged, true);
            app.AutomaticPeakFinderPanel = uipanel(app.GridLayout);
            app.AutomaticPeakFinderPanel.Title = 'Automatic Peak Finder';
            app.AutofindButton = uibutton(app.AutomaticPeakFinderPanel, 'push');
            app.AutofindButton.ButtonPushedFcn = createCallbackFcn(app, @AutofindButtonPushed, true);
            app.AutofindButton.Text = 'Autofind';
            app.PeaknbfileLabel = uilabel(app.AutomaticPeakFinderPanel);
            app.PeaknbfileLabel.Text = 'Peak nb/file';
            app.PeakNbEditField = uieditfield(app.AutomaticPeakFinderPanel, 'numeric');
            app.g_value_Field = uieditfield(app.GridLayout, 'numeric');
            app.g_value_Field.ValueDisplayFormat = '%11.6g';
            app.g_value_Field.Editable = 'off';
            app.SaveMatSwitch = uiswitch(app.GridLayout, 'slider');
            app.SaveMatSwitch.Items = {'Save Matrix Off', 'On'};
            app.SaveMatSwitch.Value = 'Save Matrix Off';
            app.ReinitializeButton = uibutton(app.GridLayout, 'push');
            app.ReinitializeButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitializeButtonPushed, true);
            app.ReinitializeButton.Text = 'Re-initialize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Pretreatment_ESR_updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end